classdef DispersionFittingApp_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        IntensitylevelEditField         matlab.ui.control.NumericEditField
        IntensitylevelLabel             matlab.ui.control.Label
        FullrangeButton                 matlab.ui.control.Button
        ShowKregionButton_2             matlab.ui.control.Button
        ShowKregionButton               matlab.ui.control.Button
        KlowerboundLabel                matlab.ui.control.Label
        KlowerboundEditField_2          matlab.ui.control.NumericEditField
        KupperboundLabel                matlab.ui.control.Label
        KupperboundEditField_2          matlab.ui.control.NumericEditField
        KSliderLabel                    matlab.ui.control.Label
        KSlider                         matlab.ui.control.Slider
        KlowerboundEditFieldLabel       matlab.ui.control.Label
        KlowerboundEditField            matlab.ui.control.NumericEditField
        KupperboundEditFieldLabel       matlab.ui.control.Label
        KupperboundEditField            matlab.ui.control.NumericEditField
        ElowerboundEditFieldLabel       matlab.ui.control.Label
        ElowerboundEditField            matlab.ui.control.NumericEditField
        EupperboundEditFieldLabel       matlab.ui.control.Label
        EupperboundEditField            matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxesEdcFit                    matlab.ui.control.UIAxes
        UIAxesParabolaFit               matlab.ui.control.UIAxes
        loaddataButton                  matlab.ui.control.Button
        ChoosefilesButtonGroup          matlab.ui.container.ButtonGroup
        ReferencefileButton             matlab.ui.control.RadioButton
        TestfileButton                  matlab.ui.control.RadioButton
        AllfilesButton                  matlab.ui.control.RadioButton
        TabGroup                        matlab.ui.container.TabGroup
        SinglegaussianTab               matlab.ui.container.Tab
        wEditField_sg                   matlab.ui.control.NumericEditField
        wEditFieldLabel                 matlab.ui.control.Label
        xcEditField_sg                  matlab.ui.control.NumericEditField
        xcEditFieldLabel                matlab.ui.control.Label
        AEditField_sg                   matlab.ui.control.NumericEditField
        AEditFieldLabel                 matlab.ui.control.Label
        FittingfunctionTextArea_sg      matlab.ui.control.TextArea
        FittingfunctionTextAreaLabel    matlab.ui.control.Label
        DoublegaussianTab               matlab.ui.container.Tab
        FitButton                       matlab.ui.control.Button
        MinPeakDistanceEditField        matlab.ui.control.NumericEditField
        MinPeakDistanceEditFieldLabel   matlab.ui.control.Label
        MinPeakHeightEditField          matlab.ui.control.NumericEditField
        MinPeakHeightEditFieldLabel     matlab.ui.control.Label
        InitialValueLabel               matlab.ui.control.Label
        PeakFinderAssistanceLabel       matlab.ui.control.Label
        MinPeakWidthEditField           matlab.ui.control.NumericEditField
        MinPeakWidthEditFieldLabel      matlab.ui.control.Label
        A2EditField_dg                  matlab.ui.control.NumericEditField
        A2EditFieldLabel                matlab.ui.control.Label
        w2EditField_dg                  matlab.ui.control.NumericEditField
        wEditFieldLabel_3               matlab.ui.control.Label
        xc2EditField_dg                 matlab.ui.control.NumericEditField
        xcEditFieldLabel_3              matlab.ui.control.Label
        A1EditField_dg                  matlab.ui.control.NumericEditField
        A1EditFieldLabel                matlab.ui.control.Label
        w1EditField_dg                  matlab.ui.control.NumericEditField
        wEditFieldLabel_2               matlab.ui.control.Label
        xc1EditField_dg                 matlab.ui.control.NumericEditField
        xcEditFieldLabel_2              matlab.ui.control.Label
        FittingfunctionTextArea_dg      matlab.ui.control.TextArea
        ChoosethecutsButtonGroup        matlab.ui.container.ButtonGroup
        K3K6Button                      matlab.ui.control.RadioButton
        K2K5Button                      matlab.ui.control.RadioButton
        K1K4Button                      matlab.ui.control.RadioButton
        KLabel                          matlab.ui.control.Label
        KLabel_2                        matlab.ui.control.Label
        FittingfunctionTextArea_2Label  matlab.ui.control.Label
        BigaussianTab                   matlab.ui.container.Tab
        FittingfunctionTextArea_3       matlab.ui.control.TextArea
        FittingfunctionTextArea_3Label  matlab.ui.control.Label
        ParabolaTab                     matlab.ui.container.Tab
        choosefilesButtonGroup          matlab.ui.container.ButtonGroup
        ReferencefileButton_2           matlab.ui.control.RadioButton
        AllfilesButton_2                matlab.ui.control.RadioButton
        LoadfileButton                  matlab.ui.control.Button
        FittingfunctionTextArea_4       matlab.ui.control.TextArea
        FittingfunctionTextArea_4Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        files % stores file names
        filepath % stores full file path
        Energy_scale % stores energy scale generated from MM's file
        K_scale % stores momentum scale generated from MM's file
        data % stores the data with out the E and K scales
        E_UB % stores the energy upper bound
        E_LB %stores the energy lower bound
        K_UB %stores the momentum upper bound
        K_LB %stores the momentum lower bound
        nK_UB 
        nK_LB
        
    end
    
    methods (Access = private)
        
        function [D, E, K] = readData(app,filepath)
            data1 = readmatrix(filepath{2}); %% to read the data file. Using readmatrix as we know the format of our file is floating and we want to use matrix form for ease of use later.
            E = data1(2:size(data1,1),1); %% scale generated from MM's code
            K = data1(1,2:size(data1,2)); %% scale generated from MM's code
            D = data1(2:size(data1,1),2:size(data1,2)); %% full data without the scales
        end
        
        function findworkingData(app,uE_UB,uE_LB,uK_UB,uK_LB)
%             [d, E, K] = readData(app,app.filepath);
            app.E_UB = find(abs(app.Energy_scale - uE_UB) < 0.005);%% find the column number for the energy upper bound
            app.E_LB = find(abs(app.Energy_scale - uE_LB) < 0.004);%% find the column number for the energy lower bound
            app.K_UB = find(abs(app.K_scale- uK_UB) < 0.004); % K range chosen by the user
            app.K_LB = find(abs(app.K_scale- uK_LB) < 0.004);          
        end
        
        function [D, E, K] = workingData(app,uE_UB,uE_LB,uK_UB,uK_LB)
            D = app.data(uE_UB(1):uE_LB(1),uK_LB:uK_UB); %% working data 
            E = app.Energy_scale(uE_UB(1):uE_LB(1)); %% working Escale             
            K = app.K_scale(uK_LB:uK_UB); %% working k scale 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ElowerboundEditField.Value = -1;
            app.EupperboundEditField.Value = 0.5;
            app.KlowerboundEditField.Value = 1;
            app.KupperboundEditField.Value = 1.5;
            app.KupperboundEditField_2.Value = -1;
            app.KlowerboundEditField_2.Value = -1.5;
        end

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            selpath =uigetdir(' ');     %% to select the folder you want to work in
            app.files = dir(fullfile(selpath,'*.txt'));     %% to store all the file names with .txt extension
            app.filepath = fullfile({app.files.folder}, {app.files.name});     %% to generate filepaths for all the txt files in the folder
            [app.data,app.Energy_scale,app.K_scale] = readData(app, app.filepath);
            [A, h] =  contourf(app.UIAxes, app.K_scale, app.Energy_scale, app.data,app.IntensitylevelEditField.Value);
            set(h,'Linecolor', 'none');
        end

        % Button pushed function: ShowKregionButton
        function ShowKregionButtonPushed(app, event)
            uE_LB = app.ElowerboundEditField.Value;
            uE_UB = app.EupperboundEditField.Value;
            uK_UB = app.KupperboundEditField.Value;
            uK_LB = app.KlowerboundEditField.Value;
                       
            findworkingData(app,uE_UB,uE_LB,uK_UB,uK_LB);
            [wD,wE,wK] = workingData(app, app.E_UB, app.E_LB, app.K_UB, app.K_LB);
            [A, h] =  contourf(app.UIAxes, wK, wE, wD,app.IntensitylevelEditField.Value);   
            set(h,'Linecolor','none');
        end

        % Button pushed function: ShowKregionButton_2
        function ShowKregionButton_2Pushed(app, event)
            uE_LB = app.ElowerboundEditField.Value;
            uE_UB = app.EupperboundEditField.Value;
            uK_UB = app.KlowerboundEditField_2.Value;
            uK_LB = app.KupperboundEditField_2.Value;
                       
            findworkingData(app,uE_UB,uE_LB,uK_UB,uK_LB);
            [wD,wE,wK] = workingData(app, app.E_UB, app.E_LB, app.K_LB, app.K_UB);
            [A, h] =  contourf(app.UIAxes, wK, wE, wD,app.IntensitylevelEditField.Value);   
            set(h,'Linecolor','none');            
        end

        % Button pushed function: FullrangeButton
        function FullrangeButtonPushed(app, event)
            [A, h] =  contourf(app.UIAxes, app.K_scale, app.Energy_scale, app.data,app.IntensitylevelEditField.Value);
            set(h,'Linecolor', 'none');            
        end

        % Value changed function: IntensitylevelEditField
        function IntensitylevelEditFieldValueChanged(app, event)
            value = app.IntensitylevelEditField.Value;
            [A, h] =  contourf(app.UIAxes, app.K_scale, app.Energy_scale, app.data,app.IntensitylevelEditField.Value);
            set(h,'Linecolor', 'none');                 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.KLabel_2 = uilabel(app.GridLayout);
            app.KLabel_2.Text = '-K';
            app.KLabel = uilabel(app.GridLayout);
            app.KLabel.Text = 'K';
            app.ChoosethecutsButtonGroup = uibuttongroup(app.GridLayout);
            app.ChoosethecutsButtonGroup.Title = 'Choose the cuts';
            app.K1K4Button = uiradiobutton(app.ChoosethecutsButtonGroup);
            app.K1K4Button.Text = 'K1-K4';
            app.K1K4Button.Value = true;
            app.K2K5Button = uiradiobutton(app.ChoosethecutsButtonGroup);
            app.K2K5Button.Text = 'K2-K5';
            app.K3K6Button = uiradiobutton(app.ChoosethecutsButtonGroup);
            app.K3K6Button.Text = 'K3-K6';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SinglegaussianTab = uitab(app.TabGroup);
            app.SinglegaussianTab.Title = 'Single gaussian';
            app.FittingfunctionTextAreaLabel = uilabel(app.SinglegaussianTab);
            app.FittingfunctionTextAreaLabel.Text = 'Fitting function';
            app.FittingfunctionTextArea_sg = uitextarea(app.SinglegaussianTab);
            app.FittingfunctionTextArea_sg.Value = {'y = A*exp(-(x-xc)^2/w)'};
            app.AEditFieldLabel = uilabel(app.SinglegaussianTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField_sg = uieditfield(app.SinglegaussianTab, 'numeric');
            app.xcEditFieldLabel = uilabel(app.SinglegaussianTab);
            app.xcEditFieldLabel.Text = 'xc';
            app.xcEditField_sg = uieditfield(app.SinglegaussianTab, 'numeric');
            app.wEditFieldLabel = uilabel(app.SinglegaussianTab);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField_sg = uieditfield(app.SinglegaussianTab, 'numeric');
            app.DoublegaussianTab = uitab(app.TabGroup);
            app.DoublegaussianTab.Title = 'Double gaussian';
            app.FittingfunctionTextArea_2Label = uilabel(app.DoublegaussianTab);
            app.FittingfunctionTextArea_2Label.Text = 'Fitting function';
            app.FittingfunctionTextArea_dg = uitextarea(app.DoublegaussianTab);
            app.FittingfunctionTextArea_dg.Value = {'y = y0+ A1*exp(-(x-xc1)^2/(2*w1^2)) + A2*exp(-(x-xc2)^2/(2*w2^2))'};
            app.xcEditFieldLabel_2 = uilabel(app.DoublegaussianTab);
            app.xcEditFieldLabel_2.Text = 'xc1';
            app.xc1EditField_dg = uieditfield(app.DoublegaussianTab, 'numeric');
            app.wEditFieldLabel_2 = uilabel(app.DoublegaussianTab);
            app.wEditFieldLabel_2.Text = 'w1';
            app.w1EditField_dg = uieditfield(app.DoublegaussianTab, 'numeric');
            app.A1EditFieldLabel = uilabel(app.DoublegaussianTab);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1EditField_dg = uieditfield(app.DoublegaussianTab, 'numeric');
            app.xcEditFieldLabel_3 = uilabel(app.DoublegaussianTab);
            app.xcEditFieldLabel_3.Text = 'xc2';
            app.xc2EditField_dg = uieditfield(app.DoublegaussianTab, 'numeric');
            app.wEditFieldLabel_3 = uilabel(app.DoublegaussianTab);
            app.wEditFieldLabel_3.Text = 'w2';
            app.w2EditField_dg = uieditfield(app.DoublegaussianTab, 'numeric');
            app.A2EditFieldLabel = uilabel(app.DoublegaussianTab);
            app.A2EditFieldLabel.Text = 'A2';
            app.A2EditField_dg = uieditfield(app.DoublegaussianTab, 'numeric');
            app.MinPeakWidthEditFieldLabel = uilabel(app.DoublegaussianTab);
            app.MinPeakWidthEditFieldLabel.Text = 'MinPeakWidth';
            app.MinPeakWidthEditField = uieditfield(app.DoublegaussianTab, 'numeric');
            app.PeakFinderAssistanceLabel = uilabel(app.DoublegaussianTab);
            app.PeakFinderAssistanceLabel.Text = 'Peak finder assistance';
            app.InitialValueLabel = uilabel(app.DoublegaussianTab);
            app.InitialValueLabel.Text = 'Initial value';
            app.MinPeakHeightEditFieldLabel = uilabel(app.DoublegaussianTab);
            app.MinPeakHeightEditFieldLabel.Text = 'MinPeakHeight';
            app.MinPeakHeightEditField = uieditfield(app.DoublegaussianTab, 'numeric');
            app.MinPeakDistanceEditFieldLabel = uilabel(app.DoublegaussianTab);
            app.MinPeakDistanceEditFieldLabel.Text = 'MinPeakDistance';
            app.MinPeakDistanceEditField = uieditfield(app.DoublegaussianTab, 'numeric');
            app.FitButton = uibutton(app.DoublegaussianTab, 'push');
            app.FitButton.Text = 'Fit';
            app.BigaussianTab = uitab(app.TabGroup);
            app.BigaussianTab.Title = 'Bi-gaussian';
            app.FittingfunctionTextArea_3Label = uilabel(app.BigaussianTab);
            app.FittingfunctionTextArea_3Label.Text = 'Fitting function';
            app.FittingfunctionTextArea_3 = uitextarea(app.BigaussianTab);
            app.ParabolaTab = uitab(app.TabGroup);
            app.ParabolaTab.Title = 'Parabola';
            app.FittingfunctionTextArea_4Label = uilabel(app.ParabolaTab);
            app.FittingfunctionTextArea_4Label.Text = 'Fitting function';
            app.FittingfunctionTextArea_4 = uitextarea(app.ParabolaTab);
            app.LoadfileButton = uibutton(app.ParabolaTab, 'push');
            app.LoadfileButton.Text = 'Load file';
            app.choosefilesButtonGroup = uibuttongroup(app.ParabolaTab);
            app.choosefilesButtonGroup.Title = 'choose files';
            app.AllfilesButton_2 = uiradiobutton(app.choosefilesButtonGroup);
            app.AllfilesButton_2.Text = 'All files';
            app.AllfilesButton_2.Value = true;
            app.ReferencefileButton_2 = uiradiobutton(app.choosefilesButtonGroup);
            app.ReferencefileButton_2.Text = 'Reference file';
            app.ChoosefilesButtonGroup = uibuttongroup(app.GridLayout);
            app.ChoosefilesButtonGroup.Title = 'Choose files';
            app.AllfilesButton = uiradiobutton(app.ChoosefilesButtonGroup);
            app.AllfilesButton.Text = 'All files';
            app.AllfilesButton.Value = true;
            app.TestfileButton = uiradiobutton(app.ChoosefilesButtonGroup);
            app.TestfileButton.Text = 'Test file';
            app.ReferencefileButton = uiradiobutton(app.ChoosefilesButtonGroup);
            app.ReferencefileButton.Text = 'Reference file';
            app.loaddataButton = uibutton(app.GridLayout, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.UIAxesParabolaFit = uiaxes(app.GridLayout);
            title(app.UIAxesParabolaFit, 'Parabola fitting')
            xlabel(app.UIAxesParabolaFit, 'X')
            ylabel(app.UIAxesParabolaFit, 'Y')
            zlabel(app.UIAxesParabolaFit, 'Z')
            app.UIAxesEdcFit = uiaxes(app.GridLayout);
            title(app.UIAxesEdcFit, 'EDC and fit results')
            xlabel(app.UIAxesEdcFit, 'X')
            ylabel(app.UIAxesEdcFit, 'Y')
            zlabel(app.UIAxesEdcFit, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'K')
            ylabel(app.UIAxes, 'Energy (eV)')
            zlabel(app.UIAxes, 'Z')
            app.EupperboundEditField = uieditfield(app.GridLayout, 'numeric');
            app.EupperboundEditFieldLabel = uilabel(app.GridLayout);
            app.EupperboundEditFieldLabel.Text = 'E upper bound';
            app.ElowerboundEditField = uieditfield(app.GridLayout, 'numeric');
            app.ElowerboundEditFieldLabel = uilabel(app.GridLayout);
            app.ElowerboundEditFieldLabel.Text = 'E lower bound';
            app.KupperboundEditField = uieditfield(app.GridLayout, 'numeric');
            app.KupperboundEditFieldLabel = uilabel(app.GridLayout);
            app.KupperboundEditFieldLabel.Text = 'K upper bound';
            app.KlowerboundEditField = uieditfield(app.GridLayout, 'numeric');
            app.KlowerboundEditFieldLabel = uilabel(app.GridLayout);
            app.KlowerboundEditFieldLabel.Text = 'K lower bound';
            app.KSlider = uislider(app.GridLayout);
            app.KSliderLabel = uilabel(app.GridLayout);
            app.KSliderLabel.Text = 'K';
            app.KupperboundEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.KupperboundLabel = uilabel(app.GridLayout);
            app.KupperboundLabel.Text = '-K upper bound';
            app.KlowerboundEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.KlowerboundLabel = uilabel(app.GridLayout);
            app.KlowerboundLabel.Text = '-K lower bound';
            app.ShowKregionButton = uibutton(app.GridLayout, 'push');
            app.ShowKregionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowKregionButtonPushed, true);
            app.ShowKregionButton.Text = 'Show K region';
            app.ShowKregionButton_2 = uibutton(app.GridLayout, 'push');
            app.ShowKregionButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowKregionButton_2Pushed, true);
            app.ShowKregionButton_2.Text = 'Show -K region';
            app.FullrangeButton = uibutton(app.GridLayout, 'push');
            app.FullrangeButton.ButtonPushedFcn = createCallbackFcn(app, @FullrangeButtonPushed, true);
            app.FullrangeButton.Text = 'Full range';
            app.IntensitylevelLabel = uilabel(app.GridLayout);
            app.IntensitylevelLabel.Text = {'Intensity '; 'level'};
            app.IntensitylevelEditField = uieditfield(app.GridLayout, 'numeric');
            app.IntensitylevelEditField.ValueChangedFcn = createCallbackFcn(app, @IntensitylevelEditFieldValueChanged, true);
            app.IntensitylevelEditField.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DispersionFittingApp_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end